classdef OLIVO_NNET_DD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        RedNeuronalTab           matlab.ui.container.Tab
        PredecirButton           matlab.ui.control.Button
        OriginalButtonGroup      matlab.ui.container.ButtonGroup
        SpectrogramButton_5      matlab.ui.control.RadioButton
        MagSpectButton_5         matlab.ui.control.RadioButton
        TiempoButton_5           matlab.ui.control.RadioButton
        ReproducirButton_5       matlab.ui.control.Button
        PrediccionTextArea       matlab.ui.control.TextArea
        PrediccionTextAreaLabel  matlab.ui.control.Label
        ReproducirButton_3       matlab.ui.control.Button
        ReproducirButton_4       matlab.ui.control.Button
        Vector4ButtonGroup       matlab.ui.container.ButtonGroup
        SpectrogramButton_4      matlab.ui.control.RadioButton
        MagSpectButton_4         matlab.ui.control.RadioButton
        TiempoButton_4           matlab.ui.control.RadioButton
        Vector2ButtonGroup       matlab.ui.container.ButtonGroup
        SpectrogramButton_2      matlab.ui.control.RadioButton
        MagSpectButton_2         matlab.ui.control.RadioButton
        TiempoButton_2           matlab.ui.control.RadioButton
        Vector3ButtonGroup       matlab.ui.container.ButtonGroup
        SpectrogramButton_3      matlab.ui.control.RadioButton
        MagSpectButton_3         matlab.ui.control.RadioButton
        TiempoButton_3           matlab.ui.control.RadioButton
        Vector1ButtonGroup       matlab.ui.container.ButtonGroup
        SpectrogramButton        matlab.ui.control.RadioButton
        MagSpectButton           matlab.ui.control.RadioButton
        TiempoButton             matlab.ui.control.RadioButton
        ReproducirButton_2       matlab.ui.control.Button
        ReproducirButton         matlab.ui.control.Button
        ID_OEditField            matlab.ui.control.EditField
        ID_OEditFieldLabel       matlab.ui.control.Label
        ID_IEditField            matlab.ui.control.EditField
        ID_IEditFieldLabel       matlab.ui.control.Label
        TcEditField              matlab.ui.control.EditField
        TcEditFieldLabel         matlab.ui.control.Label
        rEditField               matlab.ui.control.EditField
        rEditFieldLabel          matlab.ui.control.Label
        FsEditField              matlab.ui.control.EditField
        FsEditFieldLabel         matlab.ui.control.Label
        GrabarButton             matlab.ui.control.Button
        UIAxes5                  matlab.ui.control.UIAxes
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %all_graphs = {app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4, app.UIAxes5} % Description
        predictdb = "AudioDataBase\Predict\";
        vectororiginal = [];
        vectores = [];
    end
    
    methods (Access = public)
        
        function toggle_button_off(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'off');
            end
        end
        
        function toggle_button_on(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                set(button, 'Enable', 'on');
            end
        end
        
        function toggle_button(app, varargin)
            for i=1:length(varargin)
                button = varargin{i};
                state = get(button, 'Enable');
                set(button, 'Enable', ~state);
            end
        end
        
        function clear_graph(app, varargin)
            cellfun(@cla, varargin)
        end
        
        function results = radio_graph_select(app, radio, graph, data)
            graphs = ["Tiempo", "MagSpect", "Spectrogram"];
            choice = radio.SelectedObject.Text;
            [~ , idx] = ismember(choice, graphs);
            if idx == 1
                app.timegraph(graph, data)
            elseif idx == 2
                app.magspectgraph(graph, data)
            else
                app.spectrogramgraph(graph, data)
            end
        end
        
        function results = timegraph(app, graph, data)
            app.clear_graph(graph)
            Fs = str2double(app.FsEditField.Value);
            Tc = width(data(1:end-1))/Fs;
            xaxis = 0:1/Fs:Tc; 
            xlim = [0 Tc];
            xticks = 0:0.1:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = data;
            ylim = [-1 1];
            yticks = -1:0.5:1;
            ytitle = 'Amplitud';

            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)
            graph.XScale = 'linear';
            graph.XTick = xticks;
            graph.XLim = xlim;
            plot(graph, xaxis, yaxis)
            grid on;
        end
        
        function results = magspectgraph(app, graph, data)
            Fs = str2num(app.FsEditField.Value);
            app.clear_graph(graph)
            raw = data;
            Nx = length(data);
            X = fft(raw);                 % Transformación de Fourier
            X = X(1:Nx/2+1);            % Frecuencias positivas
            fx = (0:Nx/2)' * Fs/Nx;     % Dominio de frecuencia de la señal de audio

            xaxis = fx;
            % xlim = [.1 max(xaxis)];
            % xticks = 0:1000:max(xaxis);
            % xtitle = 'Frecuencia (Hz)';
            yaxis = abs(X);
            % ylim = [min(yaxis) max(yaxis)];
            % yticks = min(yaxis):max(yaxis);
            % ytitle = 'Amplitud';
            % app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            graph.XLim = [.1 4];
            graph.XTick = 2.^(-2:2);
            graph.XScale = 'log';
            graph.XTickLabel = 2.^(-2:2);
            graph.YLim = [-100 100];
            graph.YTick = -100:50:100;
            graph.XLabel.String = 'f (kHz)';
            graph.YLabel.String = 'Amplitud';
            plot(graph, xaxis*1e-3, 20*log10(yaxis));
            grid on;
        end
        
        function results = spectrogramgraph(app, graph, data)
            Fs = str2num(app.FsEditField.Value);
            app.clear_graph(graph)
            tiempo_ventana = 30*1e-3;
            tiempo_traslape = 0;
            tam_ventana = Fs*tiempo_ventana;
            tam_traslape = Fs*tiempo_traslape;
            nfft = 2^nextpow2(tam_ventana);


            [S, F, T] = spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');

            Tc = width(data(1:end-1))/Fs;
            xaxis = data;
            xlim = [0 Tc];
            xticks = 0:0.5:Tc;
            xtitle = 'Tiempo (s)';
            yaxis = F;
            ylim = [0 max(F)];
            yticks = 0:1000:max(F);
            ytitle = 'Frecuencia (Hz)';
            graph.XScale = 'linear';
            app.format_graphs(xlim, xticks, xtitle, ylim, yticks, ytitle, graph)

            spectrogram(data, tam_ventana, tam_traslape, nfft, Fs, 'yaxis');
            imagesc(graph, T, F, log(1+abs(S)));
            set(graph, 'YDir', 'normal')
            axis square
            axis tight
            colorbar
        end
        
        function results = fetch_audios(app)
            predictdbdir = dir(app.predictdb);

            [vec, ~] = audioread(app.predictdb + predictdbdir(3).name);
            app.vectororiginal = vec';
            for i=4:height(predictdbdir)
                [vec, ~] = audioread(app.predictdb + predictdbdir(i).name);
                app.vectores = [app.vectores; vec'];
            end
        end
        
        function format_graphs(app, xlim, xticks, xlabel, ylim, yticks, ylabel, varargin)
            %xlim, xticks, ylim and yticks must be arrays containing arrays
            %xlabel, ylabel son un string array

            if length(varargin) == 1
                %Eje X
                disp('hola')
                disp(varargin{1})
                varargin{1}.XGrid = "on";
                varargin{1}.XLim = xlim;
                varargin{1}.XTick = xticks;
                varargin{1}.XLabel.String = xlabel;

                %Eje Y
                varargin{1}.YGrid = "on";
                varargin{1}.YLim = ylim;
                varargin{1}.YTick = yticks;
                varargin{1}.YLabel.String = ylabel;
                return
            end

            for i=1:length(varargin)
                %Eje X
                varargin{i}.XGrid = "on";
                varargin{i}.XLim = xlim(i, :);
                varargin{i}.XTick = xticks(i, :);
                varargin{i}.XLabel.String = xlabel(i);

                %Eje Y
                varargin{i}.YGrid = "on";
                varargin{i}.YLim = ylim(i, :);
                varargin{i}.YTick = yticks(i, :);
                varargin{i}.YLabel.String = ylabel(i);
            end
        end
        
        function palabras = interpretar(app, weights)
            palabras = "";
            for i=1:width(weights)
                [~, idx]= max(weights(:,i));
                switch idx
                    case 1
                        palabras = palabras + " 0";
                    case 2
                        palabras = palabras + " 1";
                    case 3
                        palabras = palabras + " 2";
                    case 4
                        palabras = palabras + " 3";
                    case 5
                        palabras = palabras + " 4";
                    case 6
                        palabras = palabras + " 5";
                    case 7
                        palabras = palabras + " 6";
                    case 8
                        palabras = palabras + " Atletico";
                    case 9
                        palabras = palabras + " Betis";
                    case 10
                        palabras = palabras + " Girona";
                    case 11
                        palabras = palabras + " Madrid";
                    case 12
                        palabras = palabras + " Sevilla";
                    case 13
                    case 14
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Borrar contenido de los graficos
            app.clear_graph(app.UIAxes, app.UIAxes2, app.UIAxes3, app.UIAxes4, app.UIAxes5) % Borro todos los graficos
            %Colocar texto en Fs y r, deshabilitar su edicion
            set(app.FsEditField, 'Value', '8000') % Coloco Fs default
            set(app.rEditField, 'Value', '24') % Coloco resolucion default
            app.toggle_button_off(app.FsEditField, app.rEditField) % Deshabilito su edición
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            tc = str2num(app.TcEditField.Value);
            ID = str2num(app.ID_IEditField.Value);

            app.toggle_button_off(app.GrabarButton) % Deshabilito widget
            procesado_de_segmentos_input(tc, ID)
            app.toggle_button_on(app.GrabarButton) % Habilito widget

            % Guardar audio principal y segmentos en propiedades
            app.fetch_audios()
            % Graficar todas las graficas
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes5, app.vectororiginal)
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes4, app.vectores(2, :))
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes3, app.vectores(1, :))
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes2, app.vectores(4, :))
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes, app.vectores(3, :))
        end

        % Button pushed function: ReproducirButton_5
        function ReproducirButton_5Pushed(app, event)
            Fs = str2double(app.FsEditField.Value);
            r = str2double(app.rEditField.Value);
            ID = str2double(app.ID_OEditField.Value);
            Tc = (width(app.vectororiginal)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton_5)
            player = audioplayer(app.vectororiginal, Fs, r, ID);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton_5)
        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
            Fs = str2double(app.FsEditField.Value);
            r = str2double(app.rEditField.Value);
            ID = str2double(app.ID_OEditField.Value);
            audio = app.vectores(1, :);
            Tc = (width(audio)-1)/Fs;
            app.toggle_button_off(app.ReproducirButton)
            player = audioplayer(audio, Fs, r, ID);
            play(player)
            pause(Tc)
            app.toggle_button_on(app.ReproducirButton)
        end

        % Button pushed function: ReproducirButton_4
        function ReproducirButton_4Pushed(app, event)
            Fs = str2double(app.FsEditField.Value);
            r = str2double(app.rEditField.Value);
            ID = str2double(app.ID_OEditField.Value);
            audio = app.vectores(2, :);
            button = app.ReproducirButton_4;
            Tc = (width(audio)-1)/Fs;
            app.toggle_button_off(button)
            player = audioplayer(audio, Fs, r, ID);
            play(player)
            pause(Tc)
            app.toggle_button_on(button)
        end

        % Button pushed function: ReproducirButton_2
        function ReproducirButton_2Pushed(app, event)
            Fs = str2double(app.FsEditField.Value);
            r = str2double(app.rEditField.Value);
            ID = str2double(app.ID_OEditField.Value);
            audio = app.vectores(3, :);
            button = app.ReproducirButton_2;
            Tc = (width(audio)-1)/Fs;
            app.toggle_button_off(button)
            player = audioplayer(audio, Fs, r, ID);
            play(player)
            pause(Tc)
            app.toggle_button_on(button)
        end

        % Button pushed function: ReproducirButton_3
        function ReproducirButton_3Pushed(app, event)
            Fs = str2double(app.FsEditField.Value);
            r = str2double(app.rEditField.Value);
            ID = str2double(app.ID_OEditField.Value);
            audio = app.vectores(4, :);
            button = app.ReproducirButton_3;
            Tc = (width(audio)-1)/Fs;
            app.toggle_button_off(button)
            player = audioplayer(audio, Fs, r, ID);
            play(player)
            pause(Tc)
            app.toggle_button_on(button)
        end

        % Selection changed function: Vector1ButtonGroup
        function Vector1ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Vector1ButtonGroup.SelectedObject;
            app.radio_graph_select(app.Vector1ButtonGroup, app.UIAxes3, app.vectores(1, :))
        end

        % Selection changed function: Vector2ButtonGroup
        function Vector2ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Vector2ButtonGroup.SelectedObject;
            app.radio_graph_select(app.Vector2ButtonGroup, app.UIAxes4, app.vectores(2, :))
        end

        % Selection changed function: Vector3ButtonGroup
        function Vector3ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Vector3ButtonGroup.SelectedObject;
            app.radio_graph_select(app.Vector3ButtonGroup, app.UIAxes, app.vectores(3, :))
        end

        % Selection changed function: Vector4ButtonGroup
        function Vector4ButtonGroupSelectionChanged(app, event)
            selectedButton = app.Vector4ButtonGroup.SelectedObject;
            app.radio_graph_select(app.Vector4ButtonGroup, app.UIAxes2, app.vectores(4, :))
        end

        % Selection changed function: OriginalButtonGroup
        function OriginalButtonGroupSelectionChanged(app, event)
            selectedButton = app.OriginalButtonGroup.SelectedObject;
            app.radio_graph_select(app.OriginalButtonGroup, app.UIAxes5, app.vectororiginal)
        end

        % Button pushed function: PredecirButton
        function PredecirButtonPushed(app, event)
            weights = nn_predecir();
            palabras = app.interpretar(weights);
            app.PrediccionTextArea.Value = palabras;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RedNeuronalTab = uitab(app.TabGroup);
            app.RedNeuronalTab.Title = 'Red Neuronal';
            app.UIAxes = uiaxes(app.RedNeuronalTab);
            title(app.UIAxes, 'Vector 3')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RedNeuronalTab);
            title(app.UIAxes2, 'Vector 4')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RedNeuronalTab);
            title(app.UIAxes3, 'Vector 1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RedNeuronalTab);
            title(app.UIAxes4, 'Vector 2')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.RedNeuronalTab);
            title(app.UIAxes5, 'Señal Original')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.GrabarButton = uibutton(app.RedNeuronalTab, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Text = 'Grabar';
            app.FsEditFieldLabel = uilabel(app.RedNeuronalTab);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.RedNeuronalTab, 'text');
            app.rEditFieldLabel = uilabel(app.RedNeuronalTab);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.RedNeuronalTab, 'text');
            app.TcEditFieldLabel = uilabel(app.RedNeuronalTab);
            app.TcEditFieldLabel.Text = 'Tc';
            app.TcEditField = uieditfield(app.RedNeuronalTab, 'text');
            app.ID_IEditFieldLabel = uilabel(app.RedNeuronalTab);
            app.ID_IEditFieldLabel.Text = 'ID_I';
            app.ID_IEditField = uieditfield(app.RedNeuronalTab, 'text');
            app.ID_OEditFieldLabel = uilabel(app.RedNeuronalTab);
            app.ID_OEditFieldLabel.Text = 'ID_O';
            app.ID_OEditField = uieditfield(app.RedNeuronalTab, 'text');
            app.ReproducirButton = uibutton(app.RedNeuronalTab, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.ReproducirButton_2 = uibutton(app.RedNeuronalTab, 'push');
            app.ReproducirButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_2Pushed, true);
            app.ReproducirButton_2.Text = 'Reproducir';
            app.Vector1ButtonGroup = uibuttongroup(app.RedNeuronalTab);
            app.Vector1ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Vector1ButtonGroupSelectionChanged, true);
            app.Vector1ButtonGroup.Title = 'Vector 1';
            app.TiempoButton = uiradiobutton(app.Vector1ButtonGroup);
            app.TiempoButton.Text = 'Tiempo';
            app.TiempoButton.Value = true;
            app.MagSpectButton = uiradiobutton(app.Vector1ButtonGroup);
            app.MagSpectButton.Text = 'MagSpect';
            app.SpectrogramButton = uiradiobutton(app.Vector1ButtonGroup);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.Vector3ButtonGroup = uibuttongroup(app.RedNeuronalTab);
            app.Vector3ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Vector3ButtonGroupSelectionChanged, true);
            app.Vector3ButtonGroup.Title = 'Vector 3';
            app.TiempoButton_3 = uiradiobutton(app.Vector3ButtonGroup);
            app.TiempoButton_3.Text = 'Tiempo';
            app.TiempoButton_3.Value = true;
            app.MagSpectButton_3 = uiradiobutton(app.Vector3ButtonGroup);
            app.MagSpectButton_3.Text = 'MagSpect';
            app.SpectrogramButton_3 = uiradiobutton(app.Vector3ButtonGroup);
            app.SpectrogramButton_3.Text = 'Spectrogram';
            app.Vector2ButtonGroup = uibuttongroup(app.RedNeuronalTab);
            app.Vector2ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Vector2ButtonGroupSelectionChanged, true);
            app.Vector2ButtonGroup.Title = 'Vector 2';
            app.TiempoButton_2 = uiradiobutton(app.Vector2ButtonGroup);
            app.TiempoButton_2.Text = 'Tiempo';
            app.TiempoButton_2.Value = true;
            app.MagSpectButton_2 = uiradiobutton(app.Vector2ButtonGroup);
            app.MagSpectButton_2.Text = 'MagSpect';
            app.SpectrogramButton_2 = uiradiobutton(app.Vector2ButtonGroup);
            app.SpectrogramButton_2.Text = 'Spectrogram';
            app.Vector4ButtonGroup = uibuttongroup(app.RedNeuronalTab);
            app.Vector4ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Vector4ButtonGroupSelectionChanged, true);
            app.Vector4ButtonGroup.Title = 'Vector 4';
            app.TiempoButton_4 = uiradiobutton(app.Vector4ButtonGroup);
            app.TiempoButton_4.Text = 'Tiempo';
            app.TiempoButton_4.Value = true;
            app.MagSpectButton_4 = uiradiobutton(app.Vector4ButtonGroup);
            app.MagSpectButton_4.Text = 'MagSpect';
            app.SpectrogramButton_4 = uiradiobutton(app.Vector4ButtonGroup);
            app.SpectrogramButton_4.Text = 'Spectrogram';
            app.ReproducirButton_4 = uibutton(app.RedNeuronalTab, 'push');
            app.ReproducirButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_4Pushed, true);
            app.ReproducirButton_4.Text = 'Reproducir';
            app.ReproducirButton_3 = uibutton(app.RedNeuronalTab, 'push');
            app.ReproducirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_3Pushed, true);
            app.ReproducirButton_3.Text = 'Reproducir';
            app.PrediccionTextAreaLabel = uilabel(app.RedNeuronalTab);
            app.PrediccionTextAreaLabel.Text = 'Prediccion';
            app.PrediccionTextArea = uitextarea(app.RedNeuronalTab);
            app.ReproducirButton_5 = uibutton(app.RedNeuronalTab, 'push');
            app.ReproducirButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButton_5Pushed, true);
            app.ReproducirButton_5.Text = 'Reproducir';
            app.OriginalButtonGroup = uibuttongroup(app.RedNeuronalTab);
            app.OriginalButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OriginalButtonGroupSelectionChanged, true);
            app.OriginalButtonGroup.Title = 'Original';
            app.TiempoButton_5 = uiradiobutton(app.OriginalButtonGroup);
            app.TiempoButton_5.Text = 'Tiempo';
            app.TiempoButton_5.Value = true;
            app.MagSpectButton_5 = uiradiobutton(app.OriginalButtonGroup);
            app.MagSpectButton_5.Text = 'MagSpect';
            app.SpectrogramButton_5 = uiradiobutton(app.OriginalButtonGroup);
            app.SpectrogramButton_5.Text = 'Spectrogram';
            app.PredecirButton = uibutton(app.RedNeuronalTab, 'push');
            app.PredecirButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirButtonPushed, true);
            app.PredecirButton.Text = 'Predecir';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OLIVO_NNET_DD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end